classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        InitializeButton         matlab.ui.control.Button
        RunButton                matlab.ui.control.StateButton
        gridButton               matlab.ui.control.StateButton
        EnablesLabel             matlab.ui.control.Label
        waveformButton           matlab.ui.control.StateButton
        intersectButton          matlab.ui.control.StateButton
        closetrackButton         matlab.ui.control.StateButton
        lineofsightButton        matlab.ui.control.StateButton
        LoadTrackButton          matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        SetupLabel               matlab.ui.control.Label
        PotentiometersLabel      matlab.ui.control.Label
        PlaceCarButton           matlab.ui.control.Button
        SteeringPotKnobLabel     matlab.ui.control.Label
        SteeringPotKnob          matlab.ui.control.Knob
        DrivePotKnobLabel        matlab.ui.control.Label
        DrivePotKnob             matlab.ui.control.Knob
        InitialAngleSliderLabel  matlab.ui.control.Label
        InitialAngleSlider       matlab.ui.control.Slider
        SimulateLabel            matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        CameraSettingsLabel      matlab.ui.control.Label
        EditCameraButton         matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        DarenHua2020Label        matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        SimInfoLabel             matlab.ui.control.Label
        CamInfoLabel             matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %imported data
        s; 
        %constants
        aov = pi/3;
        cam_angle = pi/6;
        cam_height = 2.3;
        snr = 33;
        drive_gain;
        steering_gain;
        cursor = [0 0 0];
        crosshair;
        track;
        
        stateButton = 0;
        %plotted data
        state= [0 0 0];
        initial_state;
        speed = .005;
        car_state;
        carX;
        carY;
        run_value;
        cam_pixels = linspace(1,128,128);
        
        finish;
        finish_line;
        finishX;
        timer = 0;
        center_detect_code;        
        
        waveform_enable = 1;
        intersect_enable = 1;
        recreate_enable = 1;
        cam_line_enable = 1;
        
        cam_string = 'Center: %d\nServo: %d\nDrive: %d';
        sim_string = 'Position: %.1f, %.1f\nAngle: %d\nTime: %.2f\n';
                
        recreated_lines;
        intersects;
        cam_line;
        cam_bounds;
        waveform;
        centerIdx;
        drive_motor;
        servo_motor;
        %plotted lines
        track_inner;
        track_outer;
        car_dot;
        center_dot;
        camera_line;
        intersect1;
        intersect2;
        waveform_line;
        
        grid1 = [];
        grid2 = [];

        load_center_app;
        cam_data_app; % Description
    end
    
    methods (Access = public)
        
        function update_center(app, detect_code)
            app.center_detect_code = detect_code;
        end
        
        function update_cam_data(app, camh, cam_angle, aov)
            if ~isempty(camh)
                app.cam_height = camh;
            else
                app.cam_height = 2.3;
            end
            
            if ~isempty(cam_angle)
                app.cam_angle = cam_angle;
            else
                app.cam_angle = pi/6;
            end
            if ~isempty(aov)
                app.aov = aov;
            else
                app.aov = pi/3;
            end
            
            app.EditCameraButton.Enable = 'on';
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %on startup:
            % 1. disable drag panning on axes
            % 2. hide warnings (printing takes speed)
            % 3. initialize labels
            disableDefaultInteractivity(app.UIAxes);
            disableDefaultInteractivity(app.UIAxes2);
            warning off;           
            app.CamInfoLabel.Text = sprintf(app.cam_string,0, 0, 0);
            app.SimInfoLabel.Text = sprintf(app.sim_string,0, 0, 0, 0);
        end

        % Button pushed function: LoadTrackButton
        function LoadTrackButtonPushed(app, event)
            %first, get file path (string) with uigetfile function
            file = uigetfile('*.csv');
            figure(app.UIFigure);
            %readtable reads specified file as a matlab table
            import_track = readtable(file);
            %table2struct converts table to structure array format
            app.s = table2struct(import_track);
            %structure stored in global private variable app.s
            hold(app.UIAxes, "on");
            %plot track
            plot_track(app.UIAxes, app.s);
            
            %now, user is allowed to press other buttons
            app.lineofsightButton.Value = 1;
            app.waveformButton.Value = 1;
            app.intersectButton.Value = 1;
            app.closetrackButton.Value = 1;
            app.InitialAngleSlider.Value = 0;
            app.InitialAngleSlider.Enable = 'on';
            app.PlaceCarButton.Enable = 'on';
            app.EditCameraButton.Enable = 'on';
        end

        % Value changed function: gridButton
        function gridButtonValueChanged(app, event)
            value = app.gridButton.Value;
            minY = app.s(1).tile(2);
            minX = app.s(1).tile(1);

            for i=1:length(app.s)
                if app.s(i).tile(1)<minX
                    minX = app.s(i).tile(1);
                end
            end
            
            for i=1:length(app.s)
                if app.s(i).tile(2)<minY
                    minY = app.s(i).tile(2);
                end
            end
            %minY and minX should be bot left corner tile center
            minX = minX-2;
            minY = minY-2;
            maxX = int16(10);
            maxY = int16(10);
            %want max to be number so guarentees clean multiples
            multipleX = idivide(maxX, 4, 'floor');
            multipleY = idivide(maxY, 4, 'floor');
            maxX = (1 + multipleX) * 4;
            maxY = (1 + multipleY) * 4;
%             app.UIAxes.XLim = [minX maxX];
%             app.UIAxes.YLim = [minY maxY];
            if value
                for row = minY: 4 : maxY
                    app.grid1 = [app.grid1 line(app.UIAxes, [minX, maxY], [row, row], 'Color', 'r')];
                end
                for col = minX : 4 : maxX
                    app.grid2 = [app.grid2 line(app.UIAxes, [col, col], [minY, maxX], 'Color', 'r')];
                end  
            end
            if ~value
                %assume that if state is 0, grids exist
                delete(app.grid1);
                delete(app.grid2);
            end
        end

        % Value changed function: waveformButton
        function waveformButtonValueChanged(app, event)
            app.waveform_enable = app.waveformButton.Value;
            if app.waveform_enable
                app.waveform_line.LineStyle = '-';
                app.center_dot.Marker = 'o';
            else
                app.waveform_line.LineStyle = 'none';
                app.center_dot.Marker = 'none';
            end
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            app.run_value = app.RunButton.Value;
            index = 1; 
            while app.run_value
                app.car_state = app.state(end, 1:3);
                
                %Check if lap done
                if index>50
                    %ignore "finish" state at start
                    if (((app.finish(1)<app.car_state(2)) && (app.car_state(2)<app.finish(end))) && (round(app.car_state(1))==app.finishX(1)))
                        app.finish_line.Color = 'g';
                        break
                    end

                end
                %update button state every iter
                app.run_value = app.RunButton.Value;
                [app.intersects, app.cam_line, app.waveform, app.recreated_lines] = camera_model(app.aov, app.cam_angle, app.cam_height, app.state, app.s);
                app.centerIdx = locate_center(app.waveform);
                [app.drive_motor, app.servo_motor] = motor_control(app.centerIdx, app.drive_gain, app.steering_gain);
                
                %update global information according to enables state
                if app.recreate_enable
                    app.track_inner.XData = app.recreated_lines{1,1};
                    app.track_inner.YData = app.recreated_lines{1,2};
                    app.track_outer.XData = app.recreated_lines{2,1};
                    app.track_outer.YData = app.recreated_lines{2,2};
                            
                end
                app.car_dot.XData = app.car_state(1);
                app.car_dot.YData = app.car_state(2);
                if app.cam_line_enable
                    app.camera_line.XData = app.cam_line{1,1};
                    app.camera_line.YData = app.cam_line{1,2};
                    
                end
                if app.intersect_enable
                    if ~isempty(app.intersects{1,1}) && ~isempty(app.intersects{1,2})
                        app.intersect1.XData = app.intersects{1,1}(1);
                        app.intersect1.YData = app.intersects{1,1}(2);
                        app.intersect2.XData = app.intersects{1,2}(1);
                        app.intersect2.YData = app.intersects{1,2}(2);
                    elseif ~isempty(app.intersects{1,1})
                        app.intersect1.XData = app.intersects{1,1}(1);
                        app.intersect1.YData = app.intersects{1,1}(2);
                        app.intersect2.XData = nan;
                        app.intersect2.YData = nan;
                    elseif ~isempty(app.intersects{1,2})
                        app.intersect1.XData = nan;
                        app.intersect1.YData = nan;
                        app.intersect2.XData = app.intersects{1,2}(1);
                        app.intersect2.YData = app.intersects{1,2}(2);
                    else
                        app.intersect1.XData = nan;
                        app.intersect1.YData = nan;
                        app.intersect2.XData = nan;
                        app.intersect2.YData = nan;
                    end
                        
                end
                if app.waveform_enable
                    app.waveform_line.YData = app.waveform;
                    app.center_dot.XData = app.centerIdx;
                    try
                        app.center_dot.YData = app.waveform(int16(app.centerIdx));
                    catch ME
                        app.center_dot.YData = 6.5;
                    end
                end
                
                app.state = vehicle_model(app.servo_motor, app.drive_motor, app.car_state);
                index = index + 1;
                pause(app.speed);
                if ~mod(index,30)
                    drawnow;
                end
                app.SimInfoLabel.Text = sprintf(app.sim_string, app.car_state(1), app.car_state(2), ceil(rad2deg(app.car_state(3))), app.timer);
                app.CamInfoLabel.Text = sprintf(app.cam_string, ceil(app.centerIdx), ceil(app.servo_motor), ceil(app.drive_motor));

    
            end
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            [app.intersects, app.cam_line, app.waveform, app.recreated_lines] = camera_model(app.aov, app.cam_angle, app.cam_height, app.state, app.s);
            app.centerIdx = locate_center(app.waveform);
            [app.drive_motor, app.servo_motor] = motor_control(app.centerIdx, app.drive_gain, app.steering_gain);
            
            app.track_inner = plot(app.UIAxes, app.recreated_lines{1,1}, app.recreated_lines{1,2}, 'Color', 'b', 'LineWidth', 4);
            app.track_outer = plot(app.UIAxes, app.recreated_lines{2,1}, app.recreated_lines{2,2}, 'Color', 'r', 'LineWidth', 4);
            app.camera_line = plot(app.UIAxes, app.cam_line{1,1}, app.cam_line{1,2}, 'Color', 'b', 'LineWidth', 2);
            if ~isempty(app.intersects{1,1}) && ~isempty(app.intersects{1,2})
                app.intersect1 = plot(app.UIAxes, app.intersects{1,1}(1), app.intersects{1,1}(2), 'x', 'MarkerSize', 20, 'Color', 'k');
                app.intersect2 = plot(app.UIAxes, app.intersects{1,2}(1), app.intersects{1,2}(2), 'x', 'MarkerSize', 20, 'Color', 'k');
            elseif ~isempty(app.intersects{1,1})
                app.intersect1 = plot(app.UIAxes, app.intersects{1,1}(1), app.intersects{1,1}(2), 'x', 'MarkerSize', 20, 'Color', 'k');
                app.intersect2 = plot(app.UIAxes,nan, nan, 'x', 'MarkerSize', 20, 'Color', 'k');
            elseif ~isempty(app.intersects{1,2}) 
                app.intersect1 = plot(app.UIAxes, nan, nan, 'x', 'MarkerSize', 20, 'Color', 'k');
                app.intersect2 = plot(app.UIAxes, app.intersects{1,2}(1), app.intersects{1,2}(2), 'x', 'MarkerSize', 20, 'Color', 'k');    
            else
                app.intersect1 = plot(app.UIAxes, nan, nan, 'x', 'MarkerSize', 20, 'Color', 'k');
                app.intersect2 = plot(app.UIAxes, nan, nan, 'x', 'MarkerSize', 20, 'Color', 'k');
            end
            hold(app.UIAxes2, 'on');
            app.waveform_line = plot(app.UIAxes2, app.cam_pixels, app.waveform, 'Color', [0.3010 0.7450 0.9330], 'LineWidth', 1);
            if app.centerIdx<=128
                app.center_dot = plot(app.UIAxes2, app.centerIdx, app.waveform(int16(app.centerIdx)), 'o', 'MarkerSize', 20, 'Color', 'k'); 
            end
            app.state = vehicle_model(app.servo_motor, app.drive_motor, app.state);
            app.CamInfoLabel.Text = sprintf(app.cam_string, ceil(app.centerIdx), ceil(app.servo_motor), ceil(app.drive_motor));
            app.SimInfoLabel.Text = sprintf(app.sim_string, app.state(1), app.state(2), ceil(rad2deg(app.state(3))), app.timer);

%           app.LoadCarButton.Enable = 'on';
            app.lineofsightButton.Enable = 'on';
            app.closetrackButton.Enable = 'on';
            app.intersectButton.Enable = 'on';
            app.waveformButton.Enable = 'on';
            app.gridButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            app.ResetButton.Enable = 'on';
        end

        % Button pushed function: PlaceCarButton
        function PlaceCarButtonPushed(app, event)
        
            if app.stateButton == 0
                app.crosshair = images.roi.Crosshair('Parent',app.UIAxes,'Position',[0 0],'LineWidth',1,'Color','k');
                addlistener(app.crosshair,'MovingROI',@(src,data)displayInfo(src,data));
                app.PlaceCarButton.Text = 'Place';
                app.stateButton = 1;
            elseif app.stateButton == 1
                app.state = app.cursor;
                try
                    app.car_dot = plot(app.UIAxes, app.state(1), app.state(2), '.', 'MarkerSize', 30, 'Color', 'm');
                    
                catch ME
                    app.car_dot = plot(app.UIAxes, 0, 0, '.', 'MarkerSize', 30, 'Color', 'm'); 
                    app.state = [0 0 0];
                end
                app.finish = linspace(app.state(2)-2, app.state(2)+2, 300);
                app.finishX = zeros(1, length(app.finish));
                app.finish_line = plot(app.UIAxes, app.finishX, app.finish, 'LineStyle', '--', 'Color', 'k');       

                app.PlaceCarButton.Text = 'Place Car';
                app.stateButton = 0;
                delete(app.crosshair);
                app.DrivePotKnob.Enable = 'on';
                app.SteeringPotKnob.Enable = 'on';

                app.PlaceCarButton.Enable = 'off';
                
            end
                
            app.initial_state = app.state;
            function displayInfo(~,data)
                app.cursor(1) = data.CurrentPosition(1);
                app.cursor(2) = data.CurrentPosition(2);
                app.SimInfoLabel.Text = sprintf(app.sim_string, app.cursor(1), app.cursor(2), 0, 0);
                
            end            
       
        end

        % Value changed function: SteeringPotKnob
        function SteeringPotKnobValueChanged(app, event)
            value = app.SteeringPotKnob.Value;
            app.steering_gain = value;
            app.InitializeButton.Enable = 'on';

        end

        % Value changed function: DrivePotKnob
        function DrivePotKnobValueChanged(app, event)
            value = app.DrivePotKnob.Value;
            app.drive_gain = value;
            app.InitializeButton.Enable = 'on';
        end

        % Value changed function: InitialAngleSlider
        function InitialAngleSliderValueChanged(app, event)
            value = app.InitialAngleSlider.Value;
            app.state(end, 3) = value;
            app.SimInfoLabel.Text = sprintf(app.sim_string, app.state(1), app.state(2), ceil(rad2deg(app.state(3))), 0);
            

        end

        % Value changed function: intersectButton
        function intersectButtonValueChanged(app, event)
            app.intersect_enable = app.intersectButton.Value;
            if app.intersect_enable
                app.intersect1.Marker = 'x';
                app.intersect2.Marker = 'x';
            else
                app.intersect1.Marker = 'none';
                app.intersect2.Marker = 'none';
            end    
        end

        % Value changed function: closetrackButton
        function closetrackButtonValueChanged(app, event)
            app.recreate_enable = app.closetrackButton.Value;
            if app.recreate_enable
                app.track_inner.LineStyle = '-';
                app.track_outer.LineStyle = '-';
            else
                app.track_inner.LineStyle = 'none';
                app.track_outer.LineStyle = 'none';
            end
        end

        % Value changed function: lineofsightButton
        function lineofsightButtonValueChanged(app, event)
            app.cam_line_enable = app.lineofsightButton.Value;
            if app.cam_line_enable
                app.camera_line.LineStyle = '-';
            else
                app.camera_line.LineStyle = 'none';
            end

        end

        % Callback function
        function LoadCenterDetectButtonPushed(app, event)
            app.LoadCenterDetectButton.Enable = 'on';
            app.load_center_app = center_text(app);
        end

        % Button pushed function: EditCameraButton
        function EditCameraButtonPushed(app, event)
            app.EditCameraButton.Enable = 'on';
            app.cam_data_app = cam_value(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            delete(app.load_center_app);
            delete(app.cam_data_app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.state = app.initial_state;
            app.car_dot = plot(app.UIAxes, app.state(1), app.state(2), '.', 'MarkerSize', 30, 'Color', 'm');
            app.finish_line = plot(app.UIAxes, app.finishX, app.finish, 'LineStyle', '--', 'Color', 'k');
            plot_track(app.UIAxes, app.s);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {488, 488};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitializeButton = uibutton(app.LeftPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Enable = 'off';
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.gridButton = uibutton(app.LeftPanel, 'state');
            app.gridButton.ValueChangedFcn = createCallbackFcn(app, @gridButtonValueChanged, true);
            app.gridButton.Enable = 'off';
            app.gridButton.Text = 'grid';
            app.EnablesLabel = uilabel(app.LeftPanel);
            app.EnablesLabel.Text = 'Enables';
            app.waveformButton = uibutton(app.LeftPanel, 'state');
            app.waveformButton.ValueChangedFcn = createCallbackFcn(app, @waveformButtonValueChanged, true);
            app.waveformButton.Enable = 'off';
            app.waveformButton.Text = 'waveform';
            app.intersectButton = uibutton(app.LeftPanel, 'state');
            app.intersectButton.ValueChangedFcn = createCallbackFcn(app, @intersectButtonValueChanged, true);
            app.intersectButton.Enable = 'off';
            app.intersectButton.Text = 'intersect';
            app.closetrackButton = uibutton(app.LeftPanel, 'state');
            app.closetrackButton.ValueChangedFcn = createCallbackFcn(app, @closetrackButtonValueChanged, true);
            app.closetrackButton.Enable = 'off';
            app.closetrackButton.Text = 'close track';
            app.lineofsightButton = uibutton(app.LeftPanel, 'state');
            app.lineofsightButton.ValueChangedFcn = createCallbackFcn(app, @lineofsightButtonValueChanged, true);
            app.lineofsightButton.Enable = 'off';
            app.lineofsightButton.Text = 'line of sight';
            app.LoadTrackButton = uibutton(app.LeftPanel, 'push');
            app.LoadTrackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrackButtonPushed, true);
            app.LoadTrackButton.Text = 'Load Track';
            app.Label = uilabel(app.LeftPanel);
            app.SetupLabel = uilabel(app.LeftPanel);
            app.SetupLabel.Text = 'Setup';
            app.PotentiometersLabel = uilabel(app.LeftPanel);
            app.PotentiometersLabel.Text = 'Potentiometers';
            app.PlaceCarButton = uibutton(app.LeftPanel, 'push');
            app.PlaceCarButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceCarButtonPushed, true);
            app.PlaceCarButton.Enable = 'off';
            app.PlaceCarButton.Text = 'Place Car';
            app.SteeringPotKnobLabel = uilabel(app.LeftPanel);
            app.SteeringPotKnobLabel.Text = 'Steering Pot ';
            app.SteeringPotKnob = uiknob(app.LeftPanel, 'continuous');
            app.SteeringPotKnob.Limits = [-1 1];
            app.SteeringPotKnob.MajorTicks = [-1 -0.6 -0.2 0.2 0.6 1];
            app.SteeringPotKnob.ValueChangedFcn = createCallbackFcn(app, @SteeringPotKnobValueChanged, true);
            app.SteeringPotKnob.Enable = 'off';
            app.DrivePotKnobLabel = uilabel(app.LeftPanel);
            app.DrivePotKnobLabel.Text = 'Drive Pot ';
            app.DrivePotKnob = uiknob(app.LeftPanel, 'continuous');
            app.DrivePotKnob.Limits = [-1 1];
            app.DrivePotKnob.ValueChangedFcn = createCallbackFcn(app, @DrivePotKnobValueChanged, true);
            app.DrivePotKnob.Enable = 'off';
            app.InitialAngleSliderLabel = uilabel(app.LeftPanel);
            app.InitialAngleSliderLabel.Text = 'Initial Angle';
            app.InitialAngleSlider = uislider(app.LeftPanel);
            app.InitialAngleSlider.Limits = [0 6.28318530717959];
            app.InitialAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InitialAngleSliderValueChanged, true);
            app.InitialAngleSlider.Enable = 'off';
            app.SimulateLabel = uilabel(app.LeftPanel);
            app.SimulateLabel.Text = 'Simulate';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.CameraSettingsLabel = uilabel(app.LeftPanel);
            app.CameraSettingsLabel.Text = 'Camera Settings';
            app.EditCameraButton = uibutton(app.LeftPanel, 'push');
            app.EditCameraButton.ButtonPushedFcn = createCallbackFcn(app, @EditCameraButtonPushed, true);
            app.EditCameraButton.Enable = 'off';
            app.EditCameraButton.Text = 'Edit Camera';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'HSAVC LOGO2.png';
            app.DarenHua2020Label = uilabel(app.LeftPanel);
            app.DarenHua2020Label.Text = 'Daren Hua 2020';
            app.RightPanel = uipanel(app.GridLayout);
            app.SimInfoLabel = uilabel(app.RightPanel);
            app.SimInfoLabel.Text = '';
            app.CamInfoLabel = uilabel(app.RightPanel);
            app.CamInfoLabel.Text = '';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Car Simulator')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Camera Waveform')
            xlabel(app.UIAxes2, 'Pixels')
            app.UIAxes2.XLim = [0 128];
            app.UIAxes2.YLim = [0 8];
            app.UIAxes2.XTick = [0 32 64 96 128];
            app.UIAxes2.XTickLabel = {'0'; '32'; '64'; '96'; '128'};
            app.UIAxes2.YTick = [0 2 4 6 8];
            app.UIAxes2.YTickLabel = {'0'; '2'; '4'; '6'; '8'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end