classdef trackgen_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        InputButton                 matlab.ui.control.NumericEditField
        InputTargetTrackLengthEvenNumberGreaterthan4Label  matlab.ui.control.Label
        ShowProcessLabel            matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        ShowLocationLabel           matlab.ui.control.Label
        Switch_2                    matlab.ui.control.Switch
        ChooseFileButton            matlab.ui.control.Button
        ChooseOutputDirectoryLabel  matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        DarenHua2020Label           matlab.ui.control.Label
        GenerateTrackLabel          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        show_loc = 0;  % 1: show section locations in the figure, 0: don't show
        show_process = 0;  % 1: show section locations in the figure, 0: don't show
        target_length;
        box_trigger = 0;
        upload_trigger = 0;
        cwd;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputButton
        function InputButtonValueChanged(app, event)
            value = app.InputButton.Value;
            app.target_length = value;
            app.box_trigger = 1;
            if app.box_trigger && app.upload_trigger
                app.GenerateButton.Enable = 'on';
                app.GenerateTrackLabel.Enable = 'on';
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            app.cwd = uigetdir;
            figure(app.UIFigure);
            app.upload_trigger = 1;
            app.ChooseFileButton.Text = app.cwd;
            if app.box_trigger && app.upload_trigger
                app.GenerateButton.Enable = 'on';
                app.GenerateTrackLabel.Enable = 'on';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.show_process = str2double(value);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            app.show_loc = str2double(value);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            isEmptyAxes = isempty(isempty(get(app.UIAxes, 'Children')));
            if ~isEmptyAxes
                cla(app.UIAxes);
                title(app.UIAxes, 'Generated Track');
            end

            frequency = 0; % value range [0,5]
            full_plot = [];
            cd(app.cwd);
            % ask user to input the target track length            
            % define the initial 4-segment track loop
            track = 'LLLL';
            track_length = 4;
            s(1) = struct('dir', [ 0  1], 'weight', 2, 'angle', 90, 'loc', [ 2 2], 'tile', [ 2 0]);
            s(2) = struct('dir', [-1  0], 'weight', 2, 'angle', 90, 'loc', [ 0 4], 'tile', [ 2 4]);
            s(3) = struct('dir', [ 0 -1], 'weight', 2, 'angle', 90, 'loc', [-2 2], 'tile', [-2 4]);
            s(4) = struct('dir', [ 1  0], 'weight', 2, 'angle', 90, 'loc', [ 0 0], 'tile', [-2 0]);
            
            % define patterns for track growth & mutation
            pair = ["LL"   "RR"   "LF"   "RF"   "FL"   "FR"   "FF"   "FF"  ];
            quad = ["FLLF" "FRRF" "FLLR" "FRRL" "RLLF" "LRRF" "LRRL" "RLLR"];
            mutate = ["FLF" "FRF" "FRL" "FLR" "LRL" "RLR" "RLF" "LRF"];
            
            % initialization
            done = 0;
            overlap = 0;
            
            hold(app.UIAxes, 'on');
            
            if app.show_process == 1
                disp(track);
                full_plot = plot_track(track, track_length, s, app.show_loc, app.UIAxes);
                delay(0.5);
            end
            
            % track growth/mutation loop
            while track_length ~= app.target_length
                % track growth
                idx1 = randi([1, track_length]);    
                idx2 = mod(idx1,track_length)+1;
                two = [track(idx1) track(idx2)];
                for n = 1:7
                    pattern = convertStringsToChars(pair(n));
                    if strcmp(two, pattern)
                        % backup the current track sequence & structure
                        track_temp = track;
                        s_temp = s;
            
                        % replace original pair by quad sections
                        quad_str = convertStringsToChars(quad(n)); 
                        if idx1 == track_length
                            track_segment = [quad_str(4) track(1:idx1-1) quad_str(1:3)];
                            track = strcat(quad_str(4), track(1:idx1-1), quad_str(1:3));
                        elseif idx1 == (track_length-1)
                            track_segment = [track(1:idx1-1) quad_str];
                            track = strcat(track(1:idx1-1), quad_str);
                        elseif idx1 == 1
                            track_segment = [track(1:idx1-1) quad_str];
                            track = strcat(quad_str, track(idx1+2:track_length));
                        else
                            track_segment = [track(1:idx1-1) quad_str  track(idx1+2:track_length)];
                            track = strcat(track(1:idx1-1),quad_str, track(idx1+2:track_length));
                        end
                        track_length = track_length + 2;
                        
                        % update section structure
                        for i = 1:track_length
                            if track(i) == 'F'
                                s(i).dir = [1 0];
                                s(i).weight = 4;
                                s(i).angle = 0;
                                s(i).loc = [4 0];
                                s(i).tile = [2, 0];
                            elseif track(i) == 'L'
                                s(i).dir = [0 1];
                                s(i).weight = 2;
                                s(i).angle = 90;
                                s(i).loc = [2 2];
                                s(i).tile = [2, 0];
                            elseif track(i) == 'R'
                                s(i).dir = [0 -1];
                                s(i).weight = 2;
                                s(i).angle = -90;
                                s(i).loc = [2 -2];
                                s(i).tile = [2, 0];
                            end 
            
                            if i ~= 1
                                theta = s(i).angle; % to rotate 90 counterclockwise
                                R = [cosd(theta) -sind(theta); 
                                     sind(theta)  cosd(theta)];
                                direction = transpose(s(i-1).dir); 
                                s(i).dir = transpose(R * direction); % new direction
                                disp_x = s(i-1).dir(1)*s(i).weight - 2*s(i-1).dir(2)*sind(theta);
                                diff = abs(s(i-1).dir(2)) - abs(s(i-1).dir(1));
                                disp_y = (s(i-1).dir(2)*s(i).weight - 2*s(i-1).dir(1)*sind(theta)) * diff;
                                displacement = [disp_x disp_y];
                                s(i).loc = s(i-1).loc + displacement; % new section location
                                s(i).tile = s(i).loc - s(i).dir*2;
                            end
                        end
            
                        % check overlap
                        overlap = checkOverlap(s);
                        if overlap == 1
                            % restore original track
                            track = track_temp;
                            s = s_temp;
                            track_length = track_length - 2;
                        else
                            if app.show_process == 1
                                delete(full_plot);
                                disp(track);
                                full_plot = plot_track(track, track_length, s, app.show_loc, app.UIAxes);
                                delay(0.2);
                            end
                            break; 
                        end
                    end
                end
                
                % track mutation 
                track_length = length(track);
                for m = 1:min(round(track_length*frequency/10), 10)    
                    idx = randi([1, track_length]);
                    idx1 = mod(idx-1,track_length)+1;
                    idx2 = mod(idx,track_length)+1;
                    idx3 = mod(idx+1,track_length)+1;
                    three = [track(idx1) track(idx2) track(idx3)];
                    for n = 1:8
                        pattern = convertStringsToChars(mutate(n));
                        if strcmp(three, pattern)
                            track_temp = track;
                            s_temp = s;
                            new_pattern = convertStringsToChars(mutate(mod(n+3,8)+1));
                            track(idx1) = new_pattern(1);
                            track(idx2) = new_pattern(2);
                            track(idx3) = new_pattern(3);
                            for i = 1:track_length
                                if track(i) == 'F'
                                    s(i).dir = [1 0];
                                    s(i).weight = 4;
                                    s(i).angle = 0;
                                    s(i).loc = [4 0];
                                    s(i).tile = [2, 0];
                                elseif track(i) == 'L'
                                    s(i).dir = [0 1];
                                    s(i).weight = 2;
                                    s(i).angle = 90;
                                    s(i).loc = [2 2];
                                    s(i).tile = [2, 0];
                                elseif track(i) == 'R'
                                    s(i).dir = [0 -1];
                                    s(i).weight = 2;
                                    s(i).angle = -90;
                                    s(i).loc = [2 -2];
                                    s(i).tile = [2, 0];
                                end 
            
                                if i ~= 1
                                    theta = s(i).angle; % to rotate 90 counterclockwise
                                    R = [cosd(theta) -sind(theta); 
                                         sind(theta)  cosd(theta)];
                                    direction = transpose(s(i-1).dir); 
                                    s(i).dir = transpose(R * direction); % new direction
                                    disp_x = s(i-1).dir(1)*s(i).weight - 2*s(i-1).dir(2)*sind(theta);
                                    diff = abs(s(i-1).dir(2)) - abs(s(i-1).dir(1));
                                    disp_y = (s(i-1).dir(2)*s(i).weight - 2*s(i-1).dir(1)*sind(theta)) * diff;
                                    displacement = [disp_x disp_y];
                                    s(i).loc = s(i-1).loc + displacement; % new section location
                                    s(i).tile = s(i).loc - s(i).dir*2;
                                end
                            end
                            overlap = checkOverlap(s);
                            if overlap == 1
                                track = track_temp;
                                s = s_temp;
                            else
                                if app.show_process == 1
                                    delete(full_plot);
                                    disp(track);
                                    full_plot = plot_track(track, track_length, s, app.show_loc, app.UIAxes);
                                end
                                break; 
                            end
                        end
                    end
                end
            end
            
            %track created


            % output track info
            output_track(track, track_length, s, app.show_loc, app.UIAxes);
            app.InputButton.Value = 0;
            app.box_trigger = 0;
            app.GenerateButton.Enable = 'off';
            
            
            % ckeckOverlap function
            function overlap = checkOverlap(s)
                overlap = 0; 
                track_length = length(s);
                
                for i = 1:track_length
                    for j = i+1:track_length
                        if s(i).tile == s(j).tile
                            overlap = 1;
                            break;
                        end
                    end
                end
            end
            
            % output_track function
            function output_track(track, track_length, s, show_loc, axes)
                % plot track
                plot_track(track, track_length, s, show_loc, axes);
                % save figure as a png file
                output_filename = strcat('track_', num2str(track_length), datestr(now, '_mm-dd-yyyy-HH-MM'));               
                
                % save section structure as a csv file
                struct_filename = strcat(output_filename, '.csv');
                writetable(struct2table(s), struct_filename)
                
            end
            
            function delay(seconds)
                % function pause the program
                % seconds = delay time in seconds
                tic;
                while toc < seconds
                end
            end
            
            % plot track function
            function full_plot = plot_track(track, track_length, s, show_loc, axes)
                fp1 = [];
                fp2 = [];
                if show_loc == 1
                    fc1 = [];
                end
                radius_inner = 1;  
                radius_outer = 3;
                
                title(axes, {['\fontsize{16}Track Auto-Generation (Length = ', num2str(track_length), ')'] ['\fontsize{11}Track Sequence = ', track]});
                
                for i = 1:track_length  
                    %Tile_Center = s(i).tile
                    if track(i) == 'F'
                        if show_loc == 1
                            c1 = appviscircles(axes, s(i).loc, 0.1, 'Color', 'red','EnhanceVisibility',false);
                        end
                        if s(i).dir(1) ~= 0
                            p1 = line(axes, [s(i).loc(1),s(i).loc(1)-s(i).dir(1)*4],[s(i).loc(2)+1,s(i).loc(2)+1], 'Color', 'black','LineWidth',4);
                            p2 = line(axes, [s(i).loc(1),s(i).loc(1)-s(i).dir(1)*4],[s(i).loc(2)-1,s(i).loc(2)-1], 'Color', 'black','LineWidth',4);
                        else
                            p1 = line(axes, [s(i).loc(1)+1,s(i).loc(1)+1],[s(i).loc(2),s(i).loc(2)-s(i).dir(2)*4], 'Color', 'black','LineWidth',4);
                            p2 = line(axes, [s(i).loc(1)-1,s(i).loc(1)-1],[s(i).loc(2),s(i).loc(2)-s(i).dir(2)*4], 'Color', 'black','LineWidth',4);            
                        end
                    else
                        angle_index = atan2(s(i).dir(2), s(i).dir(1))/(pi/2)*2 + 3;
                        th = linspace((angle_index+sin(s(i).angle*pi/180))*pi/4, (angle_index+2+sin(s(i).angle*pi/180))*pi/4, 100);
                        x = radius_inner*cos(th) + s(i).loc(1) - 2*s(i).dir(2)*sin(s(i).angle*pi/180);
                        y = radius_inner*sin(th) + s(i).loc(2) + 2*s(i).dir(1)*sin(s(i).angle*pi/180);
                        p1 = plot(axes, x,y,'Color', 'black','LineWidth',4); 
                        cir_x = s(i).loc(1) - 2*s(i).dir(2)*sin(s(i).angle*pi/180);
                        cir_y = s(i).loc(2) + 2*s(i).dir(1)*sin(s(i).angle*pi/180);
                        x = radius_outer*cos(th) + cir_x;
                        y = radius_outer*sin(th) + cir_y;
                        %cir = [cir_x cir_y];
                        p2 = plot(axes, x,y,'Color', 'black','LineWidth',4); 
                        if show_loc == 1
                            c1 = appviscircles(axes, s(i).loc, 0.1, 'Color', 'red','EnhanceVisibility',false);
                        end
                        %viscircles(cir, 0.05, 'Color', 'black');
                        axis(axes, "equal");
                    end
                    fp1 = horzcat(fp1, p1);
                    fp2 = horzcat(fp2, p2);
                    if show_loc == 1
                        fc1 = horzcat(fc1, c1);
                    end
                end
                if show_loc == 1
                    full_plot = [fp1, fp2, fc1];
                else
                    full_plot = [fp1, fp2];
                end
                drawnow;
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {509, 509};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.InputButton = uieditfield(app.LeftPanel, 'numeric');
            app.InputButton.ValueChangedFcn = createCallbackFcn(app, @InputButtonValueChanged, true);
            app.InputTargetTrackLengthEvenNumberGreaterthan4Label = uilabel(app.LeftPanel);
            app.InputTargetTrackLengthEvenNumberGreaterthan4Label.Text = 'Input Target Track Length (Even Number, Greater than 4)';
            app.ShowProcessLabel = uilabel(app.LeftPanel);
            app.ShowProcessLabel.Text = 'Show Process';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.ShowLocationLabel = uilabel(app.LeftPanel);
            app.ShowLocationLabel.Text = 'Show Location';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Items = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.ChooseFileButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.ChooseOutputDirectoryLabel = uilabel(app.LeftPanel);
            app.ChooseOutputDirectoryLabel.Text = 'Choose Output Directory';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'HSAVC LOGO2.png';
            app.DarenHua2020Label = uilabel(app.LeftPanel);
            app.DarenHua2020Label.Text = 'Daren Hua 2020';
            app.GenerateTrackLabel = uilabel(app.LeftPanel);
            app.GenerateTrackLabel.Enable = 'off';
            app.GenerateTrackLabel.Text = 'Generate Track';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Generated Track')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackgen_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end